classdef interfazfrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfazGraficaUIFigure       matlab.ui.Figure
        DataMenu                      matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        TablexlsxMenu                 matlab.ui.container.Menu
        IRJPGMenu                     matlab.ui.container.Menu
        SmoothIRJPGMenu               matlab.ui.container.Menu
        FrecuencyResponseJPGMenu      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        Figures                       matlab.ui.container.TabGroup
        IR                            matlab.ui.container.Tab
        PlotIR                        matlab.ui.control.UIAxes
        IR_SelectFilter               matlab.ui.container.ButtonGroup
        OctaveButton_3                matlab.ui.control.ToggleButton
        NoFilterButton_5              matlab.ui.control.ToggleButton
        ThirdOctaveButton_3           matlab.ui.control.ToggleButton
        OctaveFiltersDropDownLabel_5  matlab.ui.control.Label
        IR_OctaveFilter_5             matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDownLabel_5  matlab.ui.control.Label
        IR_ThirdsOctaveFilters_5      matlab.ui.control.DropDown
        SmoothIR                      matlab.ui.container.Tab
        PlotSmoothIR                  matlab.ui.control.UIAxes
        SmoothIR_SelectFilter         matlab.ui.container.ButtonGroup
        OctaveButton_2                matlab.ui.control.ToggleButton
        NoFilterButton_4              matlab.ui.control.ToggleButton
        ThirdOctaveButton_2           matlab.ui.control.ToggleButton
        OctaveFiltersDropDownLabel_4  matlab.ui.control.Label
        IR_OctaveFilter_4             matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDownLabel_4  matlab.ui.control.Label
        IR_ThirdsOctaveFilters_4      matlab.ui.control.DropDown
        RegresionLinealCheckBox       matlab.ui.control.CheckBox
        FrecuencyResponseTab          matlab.ui.container.Tab
        PlotFrecuencyResponse         matlab.ui.control.UIAxes
        OctaveFiltersDropDownLabel_3  matlab.ui.control.Label
        IR_OctaveFilter_3             matlab.ui.control.DropDown
        ThirdsOctaveFiltersDropDownLabel_3  matlab.ui.control.Label
        IR_ThirdsOctaveFilters_3      matlab.ui.control.DropDown
        SelectFilterButtonGroup       matlab.ui.container.ButtonGroup
        OctaveButton                  matlab.ui.control.ToggleButton
        NoFilterButton_3              matlab.ui.control.ToggleButton
        ThirdOctaveButton             matlab.ui.control.ToggleButton
        Table                         matlab.ui.container.Tab
        Tabla                         matlab.ui.control.Table
        DropDown                      matlab.ui.control.DropDown
        Operaciones                   matlab.ui.container.TabGroup
        CalibrateTab                  matlab.ui.container.Tab
        PlaypinknoisetocalibrateyoursystemLabel  matlab.ui.control.Label
        dBLabel                       matlab.ui.control.Label
        Vumetro                       matlab.ui.control.LinearGauge
        Switch                        matlab.ui.control.RockerSwitch
        PlayRecTab                    matlab.ui.container.Tab
        StartHzLabel                  matlab.ui.control.Label
        StartHzEditField              matlab.ui.control.NumericEditField
        EndHzLabel                    matlab.ui.control.Label
        EndHzEditField                matlab.ui.control.NumericEditField
        DurationSEditFieldLabel       matlab.ui.control.Label
        DurationSEditField            matlab.ui.control.NumericEditField
        StartMeasurementButton        matlab.ui.control.Button
        PlayandrecordasinesweepinordertoperformthemeasurementLabel  matlab.ui.control.Label
        SelectyourDeviceLabel         matlab.ui.control.Label
        SelectyourDeviceListBox       matlab.ui.control.ListBox
        RepetitionsEditFieldLabel     matlab.ui.control.Label
        RepetitionsEditField          matlab.ui.control.NumericEditField
        ImportTab                     matlab.ui.container.Tab
        ImportButton                  matlab.ui.control.StateButton
        CalculateButton               matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        EXITButton                    matlab.ui.control.Button
    end


    methods (Access = private)

        % Value changed function: Switch
        function PlayNoiseButtonPushed(app, event)
             val = app.Switch.Value;
             if val == "On"
                 pinkNoise(60);
                 [Pink, Fs] = audioread("Pink Noise.wav");
                 sound(Pink, Fs);
                 
                 app.Vumetro.Value = 0;
                 
             end
             if val == "Off"
                 clear sound
                 app.Vumetro.Value = -20;
                 delete('Pink Noise.wav')
             end
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)

            
            % Objetos
            f1 = app.StartHzEditField.Value;
            f2 = app.EndHzEditField.Value;
            Time = app.DurationSEditField.Value;
            Device = app.SelectyourDeviceListBox.Value;
            Repetitions = app.RepetitionsEditField.Value;

            
            %%%%%%%%%% PlayRec %%%%%%%%%%
            [Ir] = playRec(f1,f2,Time,Device,Repetitions);
            y = Ir.amplitudvector;
            Irfs = Ir.SampleRate;

           
            %%%%%%%%%% Ploteo de respuesta en Frecuencia %%%%%%%%%%
            dBy = 20*log10(fft(y));
            semilogx(app.PlotFrecuencyResponse,abs(dBy));                            %Grafico con x
            app.PlotFrecuencyResponse.XLim = [0 22050];                            %Logaritmico
            app.PlotFrecuencyResponse.XTickMode = 'manual';
            app.PlotFrecuencyResponse.XTick = ([10 31 63 125 250 ...               %Valores que se van a                                        
                                               500 1000 2000 4000 8000 20000]);    %mostrar de x
            
            %%%%%%%%%% Ploteo de respuesta al Impulso %%%%%%%%%%
            x = 0:1/Irfs:(length(y)-1)/Irfs;
            plot(app.PlotIR , x, y);                                
            app.PlotIR.XLim = [0 (length(y)-1)/Irfs];
            
            %%%%%%%%%% Ploteo de Smooth Respuesta al Impulso %%%%%%%%%%
            Suavizado = suavizado(Ir);
            plot(app.PlotSmoothIR,x,Suavizado);
            app.PlotSmoothIR.XLim = [0 (length(y)-1)/Irfs];


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Octave Filter"
                ColumnNames = ({"Parameters", "125 Hz",...
                                                "250 Hz", "1000 Hz", "2000 Hz", "4000 Hz",...
                                                "8000 Hz"})
                                            
            elseif value == "Third Octave Filter"
                ColumnNames = ({"Parameters", "125 Hz",...
                                                "160 Hz", "200 Hz", "250 Hz", "315 Hz", "400 Hz",...
                                                "500 Hz", "630 Hz", "800 Hz", "1000 Hz", "1250 Hz",...
                                                "1600 Hz", "2000 Hz", "3150 Hz", "3500 Hz", "4000 Hz",...
                                                "5000 Hz", "6300 Hz", "8000 Hz"})
            elseif value == "No Filter"
                ColumnNames = ({"Parameters", "Values"});
            end
            
            app.Tabla.ColumnName = ColumnNames;
                
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.InterfazGraficaUIFigure)
        end

        % Selection changed function: SelectFilterButtonGroup
        function SelectFilterButtonGroupSelectionChanged(app, event)
           
           y = audioread('Recorded Signal1.wav');
            
            selectedButton = app.SelectFilterButtonGroup.SelectedObject;
            
            if selectedButton.Text == "No Filter"
                
                 y = 20*log10(fft(y));
                 semilogx(app.PlotFrecuencyResponse,abs(y));                            %Grafico con x
                 app.PlotFrecuencyResponse.XLim = [0 10000];                            %Logaritmico
                 app.PlotFrecuencyResponse.XTickMode = 'manual';
                 app.PlotFrecuencyResponse.XTick = ([125 250 ...               %Valores que se van a
                                                    500 1000 2000 4000 8000]);    %mostrar de x
            end

        end

        % Selection changed function: IR_SelectFilter
        function IR_SelectFilterSelectionChanged(app, event)
%             [y, fs] = impulseResponse();
            
            selectedButton = app.IR_SelectFilter.SelectedObject;
            
            switch selectedButton.Text 
                case 'No Filter'
%                     x = 0:seconds(1/fs):seconds((length(y)-1)/fs);
%                     plot(app.PlotIR , x, y);
%                     app.PlotIR.XLim = [0 (length(y)-1)/fs];
                case 'Octave'
                    app.IR_OctaveFilters_5.Visible = 'on';
                    app.IR_ThirdsOctaveFilters_3.Visible = 'off';
                    
                    
                case 'Third Octave'
                    app.IR_ThirdsOctaveFilters_3.Visible = 'on';
                    app.IR_OctaveFilters_5.Visible = 'off';
                    
            end
            
        end

        % Value changed function: ImportButton
        function ImportButtonValueChanged(app, event)
            Signal = audioImport();
            
            Ir = Signal.amplitudvector;
            Irfs = Signal.SampleRate;
                        
            %%%%%%%%%% Ploteo de respuesta en Frecuencia %%%%%%%%%%
            dBy = 20*log10(fft(Ir));
            semilogx(app.PlotFrecuencyResponse,abs(dBy));                            %Grafico con x
            app.PlotFrecuencyResponse.XLim = [0 22050];                            %Logaritmico
            app.PlotFrecuencyResponse.XTickMode = 'manual';
            app.PlotFrecuencyResponse.XTick = ([10 31 63 125 250 ...               %Valores que se van a                                        
                                               500 1000 2000 4000 8000 20000]);    %mostrar de x
            
            %%%%%%%%%% Ploteo de respuesta al Impulso %%%%%%%%%%
            x = 0:1/Irfs:(length(Ir)-1)/Irfs;
            plot(app.PlotIR , x, Ir);                                
            app.PlotIR.XLim = [0 (length(Ir)-1)/Irfs];
            
            %%%%%%%%%% Ploteo de Smooth Respuesta al Impulso %%%%%%%%%%
            Suavizado = suavizado(Signal);
            plot(app.PlotSmoothIR,x,Suavizado);
            app.PlotSmoothIR.XLim = [0 (length(Ir)-1)/Irfs];
            app.PlotSmoothIR.YLim = [-90 10];
            
%             %%%%%%%%%% Parametros %%%%%%%%%%
%             [EDT,T20,T30,C80,D50] = allparameters(Suavizado,Signal);
%             Parametros = ['EDT' EDT;'T10' T10; 'T20' T20; 'T30' T30; 'C80' C80; 'D50' D50];
%             DataSet{1} = Parametros(:,1);
%             DataSet{1,2} = Parametros(:,2);


        end

        % Value changed function: SelectyourDeviceListBox
        function SelectyourDeviceListBoxValueChanged(app, event)
            devices = getAudioDevices(audioPlayerRecorder); %Dispositivos Aptos
            app.SelectyourDeviceListBox.Items = devices;    %Dispositivos Mostrados en la Lista
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete('inverted.wav','Sine Sweep.wav');    %Borrado de Archivos Temporales
            
            number = app.RepetitionsEditField.Value;
            a = 1;
            for i=1:number
                ToDelete = ['Recorded Signal' num2str(a) '.wav'];
                delete(ToDelete);
                a = a + 1;
            end
            
            close(app.InterfazGraficaUIFigure); %Cerrar Aplicacion
        end

        % Value changed function: IR_ThirdsOctaveFilters_5
        function IR_ThirdsOctaveFilters_5ValueChanged(app, event)
            value = app.IR_ThirdsOctaveFilters_5.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfazGraficaUIFigure = uifigure;
            app.InterfazGraficaUIFigure.Name = 'Interfaz Grafica';
            app.DataMenu = uimenu(app.InterfazGraficaUIFigure);
            app.DataMenu.Text = 'Data';
            app.ExportMenu = uimenu(app.DataMenu);
            app.ExportMenu.Text = 'Export';
            app.TablexlsxMenu = uimenu(app.ExportMenu);
            app.TablexlsxMenu.Text = 'Table (.xlsx)';
            app.IRJPGMenu = uimenu(app.ExportMenu);
            app.IRJPGMenu.Text = 'IR (JPG)';
            app.SmoothIRJPGMenu = uimenu(app.ExportMenu);
            app.SmoothIRJPGMenu.Text = 'Smooth IR (JPG)';
            app.FrecuencyResponseJPGMenu = uimenu(app.ExportMenu);
            app.FrecuencyResponseJPGMenu.Text = 'Frecuency Response(JPG)';
            app.ExitMenu = uimenu(app.InterfazGraficaUIFigure);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Figures = uitabgroup(app.InterfazGraficaUIFigure);
            app.IR = uitab(app.Figures);
            app.IR.Title = 'IR';
            app.PlotIR = uiaxes(app.IR);
            title(app.PlotIR, 'IR')
            xlabel(app.PlotIR, 'Time [Sec]')
            ylabel(app.PlotIR, 'Amplitude [dB]')
            app.IR_SelectFilter = uibuttongroup(app.IR);
            app.IR_SelectFilter.SelectionChangedFcn = createCallbackFcn(app, @IR_SelectFilterSelectionChanged, true);
            app.IR_SelectFilter.Title = 'Select Filter';
            app.OctaveButton_3 = uitogglebutton(app.IR_SelectFilter);
            app.OctaveButton_3.Text = 'Octave ';
            app.NoFilterButton_5 = uitogglebutton(app.IR_SelectFilter);
            app.NoFilterButton_5.Text = 'No Filter';
            app.NoFilterButton_5.Value = true;
            app.ThirdOctaveButton_3 = uitogglebutton(app.IR_SelectFilter);
            app.ThirdOctaveButton_3.Text = 'Third Octave';
            app.OctaveFiltersDropDownLabel_5 = uilabel(app.IR);
            app.OctaveFiltersDropDownLabel_5.Text = ' Octave Filters';
            app.IR_OctaveFilter_5 = uidropdown(app.IR);
            app.IR_OctaveFilter_5.Items = {'125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz'};
            app.IR_OctaveFilter_5.Value = '1000 Hz';
            app.ThirdsOctaveFiltersDropDownLabel_5 = uilabel(app.IR);
            app.ThirdsOctaveFiltersDropDownLabel_5.Text = 'Thirds Octave Filters';
            app.IR_ThirdsOctaveFilters_5 = uidropdown(app.IR);
            app.IR_ThirdsOctaveFilters_5.Items = {'125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz'};
            app.IR_ThirdsOctaveFilters_5.ValueChangedFcn = createCallbackFcn(app, @IR_ThirdsOctaveFilters_5ValueChanged, true);
            app.IR_ThirdsOctaveFilters_5.Value = '1000 Hz';
            app.SmoothIR = uitab(app.Figures);
            app.SmoothIR.Title = 'Smooth IR';
            app.PlotSmoothIR = uiaxes(app.SmoothIR);
            title(app.PlotSmoothIR, 'Smooth IR')
            xlabel(app.PlotSmoothIR, 'Time [Sec]')
            ylabel(app.PlotSmoothIR, 'Amplitude [dB]')
            app.SmoothIR_SelectFilter = uibuttongroup(app.SmoothIR);
            app.SmoothIR_SelectFilter.Title = 'Select Filter';
            app.OctaveButton_2 = uitogglebutton(app.SmoothIR_SelectFilter);
            app.OctaveButton_2.Text = 'Octave ';
            app.NoFilterButton_4 = uitogglebutton(app.SmoothIR_SelectFilter);
            app.NoFilterButton_4.Text = 'No Filter';
            app.NoFilterButton_4.Value = true;
            app.ThirdOctaveButton_2 = uitogglebutton(app.SmoothIR_SelectFilter);
            app.ThirdOctaveButton_2.Text = 'Third Octave';
            app.OctaveFiltersDropDownLabel_4 = uilabel(app.SmoothIR);
            app.OctaveFiltersDropDownLabel_4.Text = ' Octave Filters';
            app.IR_OctaveFilter_4 = uidropdown(app.SmoothIR);
            app.IR_OctaveFilter_4.Items = {'31,5 Hz', '63 Hz', '125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz', '16000 Hz'};
            app.IR_OctaveFilter_4.Value = '31,5 Hz';
            app.ThirdsOctaveFiltersDropDownLabel_4 = uilabel(app.SmoothIR);
            app.ThirdsOctaveFiltersDropDownLabel_4.Text = 'Thirds Octave Filters';
            app.IR_ThirdsOctaveFilters_4 = uidropdown(app.SmoothIR);
            app.IR_ThirdsOctaveFilters_4.Items = {'25 Hz', '31,5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10000 Hz', '12500 Hz', '16000 Hz'};
            app.IR_ThirdsOctaveFilters_4.Value = '25 Hz';
            app.RegresionLinealCheckBox = uicheckbox(app.SmoothIR);
            app.RegresionLinealCheckBox.Text = 'Regresion Lineal';
            app.FrecuencyResponseTab = uitab(app.Figures);
            app.FrecuencyResponseTab.Title = 'Frecuency Response';
            app.PlotFrecuencyResponse = uiaxes(app.FrecuencyResponseTab);
            title(app.PlotFrecuencyResponse, 'Frecuency Response')
            xlabel(app.PlotFrecuencyResponse, 'Frecuency (Hz)')
            ylabel(app.PlotFrecuencyResponse, 'Amplitude [dB]')
            app.OctaveFiltersDropDownLabel_3 = uilabel(app.FrecuencyResponseTab);
            app.OctaveFiltersDropDownLabel_3.Text = ' Octave Filters';
            app.IR_OctaveFilter_3 = uidropdown(app.FrecuencyResponseTab);
            app.IR_OctaveFilter_3.Items = {'31,5 Hz', '63 Hz', '125 Hz', '250 Hz', '500 Hz', '1000 Hz', '2000 Hz', '4000 Hz', '8000 Hz', '16000 Hz'};
            app.IR_OctaveFilter_3.Value = '31,5 Hz';
            app.ThirdsOctaveFiltersDropDownLabel_3 = uilabel(app.FrecuencyResponseTab);
            app.ThirdsOctaveFiltersDropDownLabel_3.Text = 'Thirds Octave Filters';
            app.IR_ThirdsOctaveFilters_3 = uidropdown(app.FrecuencyResponseTab);
            app.IR_ThirdsOctaveFilters_3.Items = {'25 Hz', '31,5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '3150 Hz', '3500 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10000 Hz', '12500 Hz', '16000 Hz'};
            app.IR_ThirdsOctaveFilters_3.Value = '25 Hz';
            app.SelectFilterButtonGroup = uibuttongroup(app.FrecuencyResponseTab);
            app.SelectFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectFilterButtonGroupSelectionChanged, true);
            app.SelectFilterButtonGroup.Title = 'Select Filter';
            app.OctaveButton = uitogglebutton(app.SelectFilterButtonGroup);
            app.OctaveButton.Text = 'Octave ';
            app.NoFilterButton_3 = uitogglebutton(app.SelectFilterButtonGroup);
            app.NoFilterButton_3.Text = 'No Filter';
            app.NoFilterButton_3.Value = true;
            app.ThirdOctaveButton = uitogglebutton(app.SelectFilterButtonGroup);
            app.ThirdOctaveButton.Text = 'Third Octave';
            app.Table = uitab(app.Figures);
            app.Table.Title = 'Table';
            app.Tabla = uitable(app.Table);
            app.Tabla.ColumnName = {'Parameter'; '31,5 Hz'; '63 Hz'; '125 Hz '; '250 Hz'; '500 Hz'; '1000 Hz'; '2000 Hz'; '4000 Hz'; '8000 Hz'; ''};
            app.Tabla.RowName = {};
            app.Tabla.ColumnEditable = [false false false false false false false false false false true];
            app.DropDown = uidropdown(app.Table);
            app.DropDown.Items = {'Octave Filter', 'Third Octave Filter', 'No Filter'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No Filter';
            app.Operaciones = uitabgroup(app.InterfazGraficaUIFigure);
            app.CalibrateTab = uitab(app.Operaciones);
            app.CalibrateTab.Title = 'Calibrate';
            app.PlaypinknoisetocalibrateyoursystemLabel = uilabel(app.CalibrateTab);
            app.PlaypinknoisetocalibrateyoursystemLabel.Text = 'Play pink noise to calibrate your system.';
            app.dBLabel = uilabel(app.CalibrateTab);
            app.dBLabel.Text = '[dB]';
            app.Vumetro = uigauge(app.CalibrateTab, 'linear');
            app.Vumetro.Limits = [-20 4];
            app.Vumetro.Value = -20;
            app.Switch = uiswitch(app.CalibrateTab, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @PlayNoiseButtonPushed, true);
            app.PlayRecTab = uitab(app.Operaciones);
            app.PlayRecTab.Title = 'Play/Rec';
            app.StartHzLabel = uilabel(app.PlayRecTab);
            app.StartHzLabel.Text = 'Start [Hz]';
            app.StartHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.StartHzEditField.Limits = [0 20000];
            app.EndHzLabel = uilabel(app.PlayRecTab);
            app.EndHzLabel.Text = 'End [Hz]';
            app.EndHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.EndHzEditField.Limits = [0 40000];
            app.DurationSEditFieldLabel = uilabel(app.PlayRecTab);
            app.DurationSEditFieldLabel.Text = 'Duration [S]';
            app.DurationSEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.DurationSEditField.Limits = [0 60];
            app.StartMeasurementButton = uibutton(app.PlayRecTab, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel = uilabel(app.PlayRecTab);
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel.Text = ' Play and record a sine sweep in order to perform the measurement.';
            app.SelectyourDeviceLabel = uilabel(app.PlayRecTab);
            app.SelectyourDeviceLabel.Text = 'Select your Device';
            app.SelectyourDeviceListBox = uilistbox(app.PlayRecTab);
            app.SelectyourDeviceListBox.Items = {'Select Device'};
            app.SelectyourDeviceListBox.ValueChangedFcn = createCallbackFcn(app, @SelectyourDeviceListBoxValueChanged, true);
            app.SelectyourDeviceListBox.Value = {};
            app.RepetitionsEditFieldLabel = uilabel(app.PlayRecTab);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.RepetitionsEditField.Limits = [0 60];
            app.RepetitionsEditField.Value = 1;
            app.ImportTab = uitab(app.Operaciones);
            app.ImportTab.Title = 'Import';
            app.ImportButton = uibutton(app.ImportTab, 'state');
            app.ImportButton.ValueChangedFcn = createCallbackFcn(app, @ImportButtonValueChanged, true);
            app.ImportButton.Text = 'Import';
            app.CalculateButton = uibutton(app.ImportTab, 'state');
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.ImportTab);
            app.Label.Text = {''; 'Select the impulse responses that you want to import. '; 'Remember that they must have the same sampling frequency'};
            app.EXITButton = uibutton(app.InterfazGraficaUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfazfrafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfazGraficaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfazGraficaUIFigure)
        end
    end
end